classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UploadGrayscaleImageButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: UploadGrayscaleImageButton
        function UploadGrayscaleImageButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*', 'Pick an image');
            filename = strcat(pathname, filename);
            if endsWith(filename, '.jpg', 'IgnoreCase', true)
                % Display the image
                imshow(filename, 'Parent', app.UIAxes);
                stego = 'D:\desktop\stego.png';
                cover = 'D:\desktop\cover.png';
                h = Untitled(filename);
                if h == 1
                    imshow(stego, 'Parent', app.UIAxes2);
                else
                    imshow(cover, 'Parent', app.UIAxes2);
                end
            else
                % Load and display the error image
                errorImage = 'D:\desktop\error.png';
                imshow(errorImage, 'Parent', app.UIAxes2);
            end


        end

        % Callback function
        function UploadRGBImageButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*', 'Pick an image');
            filename=strcat(pathname,filename);
            rgbImage = imread(filename);
            imwrite(rgbImage,'input.jpg');
            pythonScriptPath = 'D:\desktop\new matlab\grayscale.py';
            system(['python ', pythonScriptPath]);
            imshow(filename,'Parent',app.UIAxes);
            stego='D:\desktop\stego.png';
            cover='D:\desktop\cover.png';
            h=Untitled('gray.jpg');
            if h == 1
                imshow(stego,'Parent',app.UIAxes2);
            else
                imshow(cover,'Parent',app.UIAxes2);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadGrayscaleImageButton = uibutton(app.UIFigure, 'push');
            app.UploadGrayscaleImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadGrayscaleImageButtonPushed, true);
            app.UploadGrayscaleImageButton.Text = 'Upload Grayscale Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Chosen image')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Result')
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end